classdef ChangePassword < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HTML      matlab.ui.control.HTML
    end

    
    properties (Access = public)

        MainApp;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            
            app.MainApp = MainApp;

        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            
            data = app.HTML.Data;
            prevPassword = data{1};
            newPassword = data{2};
            confirmPassword = data{3};
            loadedVar = load('accounts.mat');            
            Accounts = loadedVar.Accounts;

            if ~isequal(StringToHashedHex(prevPassword), Accounts.(app.MainApp.LoggedInUserId).Password)

                uialert(app.UIFigure,...
                    'Invalid Previous password',...
                    'Password error');

                return;

            end

            if isequal(prevPassword, newPassword)

                uialert(app.UIFigure,...
                    'New password and Preview password should be different',...
                    'Password error');

                return;

            end

            if ~isequal(newPassword, confirmPassword)

                uialert(app.UIFigure,...
                    'New password and Confirm password should be matched',...
                    'Password error');

                return;

            end

            [isValid, stateMsg] = ValidateInput('Password', newPassword, app.MainApp.LoggedInUserId);

            if ~isValid

                uialert(app.UIFigure, stateMsg, 'Password error'); 

                return; 
            
            end
            
            hashedPassword = StringToHashedHex(newPassword);
            Accounts.(app.MainApp.LoggedInUserId).Password = hashedPassword;
            save("accounts.mat", "Accounts")
            msg = 'The password has been changed successfully';
            selection = uiconfirm(app.UIFigure, msg, 'Confirm', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');
            UIFigureCloseRequest(app, []);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'ChangePasswordUI.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChangePassword(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end