classdef LoginWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        HTML      matlab.ui.control.HTML
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        MainApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            if isdeployed; app.UIFigure.WindowStyle = "modal"; end            
            
            app.MainApp = MainApp;
            WindowPositionToCenter(app.UIFigure, app.MainApp.UIFigure);
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            id = data{1};
            password = data{2};
            [app.MainApp.IsLoggedIn, app.MainApp.CurrentTier, stateMsg] = LoginAccount(id, password);
            app.Label.Text = stateMsg;
            if app.MainApp.IsLoggedIn 
                app.MainApp.CurrentId = id; 
                UIFigureCloseRequest(app, []); 
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'LoginUI.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Please enter your ID';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end