classdef ManageAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout7                 matlab.ui.container.GridLayout
        DoneButton                  matlab.ui.control.Button
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        CreateAccountPanel          matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        CreateAccountButton         matlab.ui.control.Button
        PhoneNumberEditField_2      matlab.ui.control.EditField
        EmailSettingButton_2        matlab.ui.control.Button
        EmailDomainDropDown_2       matlab.ui.control.DropDown
        Label_2                     matlab.ui.control.Label
        EmailNameEditField_2        matlab.ui.control.EditField
        AffiliationSettingButton_2  matlab.ui.control.Button
        AffiliationDropdown_2       matlab.ui.control.DropDown
        TierDropDown_2              matlab.ui.control.DropDown
        NameEditField_2             matlab.ui.control.EditField
        PasswordEditField_2         matlab.ui.control.EditField
        IDEditField_2               matlab.ui.control.EditField
        ResetPasswordButton         matlab.ui.control.Button
        InformationPanel            matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        AuthoritySettingButton      matlab.ui.control.Button
        ApplyChangesButton          matlab.ui.control.Button
        PhoneNumberEditField        matlab.ui.control.EditField
        PhoneEditFieldLabel         matlab.ui.control.Label
        EmailSettingButton          matlab.ui.control.Button
        EmailDomainDropDown         matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        EmailNameEditField          matlab.ui.control.EditField
        EmailEditFieldLabel         matlab.ui.control.Label
        AffiliationSettingButton    matlab.ui.control.Button
        AffiliationDropDown         matlab.ui.control.DropDown
        AffiliationDropDownLabel    matlab.ui.control.Label
        TierDropDown                matlab.ui.control.DropDown
        TierDropDownLabel           matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        IDEditField                 matlab.ui.control.EditField
        IDEditFieldLabel            matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        DeleteButton                matlab.ui.control.Button
        ListBox                     matlab.ui.control.ListBox
    end

    
    properties (Access = private)                

        OrgAccounts;

        TIER_ADMIN_STRING = 'Admin';
        TIER_LIST = {'Admin', 'Manager', 'Senior', 'Staff'};

        ACCOUNT_FORM_TYPE_AFFILIATION = 'Affiliation';
        ACCOUNT_FORM_TYPE_MAILDOMAIN = 'EmailDomain';
        ACCOUNT_FILENAME = 'accounts.mat';
        ACCOUNT_FORMS_FILENAME = 'account_forms.mat';
        ACCOUNT_FORMS_VARIABLENAME = 'AccountForms';

    end    
    
    properties (Access = public)
        
        MainApp;
        Accounts;

    end
    
    methods (Access = private)
        
        function InitialLoadAccountList(app)  

            if ~isfile(app.ACCOUNT_FILENAME)                

                disp('Account information is missing or corrupted. Contact the manufacturer.');

                return;

            end

            loadedVar = load(app.ACCOUNT_FILENAME);
            app.OrgAccounts = loadedVar.Accounts;
            app.Accounts = app.OrgAccounts;
            RenewAccountList(app);

        end

        function RenewAccountList(app)

            app.ListBox.Items = fieldnames(app.Accounts);

        end

        function ApplyAuthoritySetting(app)

            % Create Option
            if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option1 == 1 % Deny

                app.CreateAccountPanel.Visible = false;

            elseif app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option1 == 2 % Lower tiers
                
                % Renew Create Authority
                tiers = app.TIER_LIST;
                tierIdx = find(strcmp(tiers, app.Accounts.(app.MainApp.LoggedInUserId).Tier));

                if tierIdx > 1

                    tiers(1:tierIdx-1) = '';

                end

                app.TierDropDown_2.Items = tiers;

            elseif app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option1 == 3 % All tiers

                app.TierDropDown_2.Items = app.TIER_LIST;

            end

            % Edit Option
            if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option2 == 1 % Deny

                app.InformationPanel.Visible = false;
                app.ResetPasswordButton.Visible = false;

            else

                % Edit - Tier
                if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option3 == 1 % Deny

                    app.TierDropDown.Enable = false;

                end

                % Edit - Authority
                if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option4 == 1 % Deny

                    app.AuthoritySettingButton.Enable = false;

                end

            end

            % Delete Option
            if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option5 == 1 % Deny

                app.DeleteButton.Visible = false;

            end

        end

        function ApplyAuthorityTarget(app)

            % Compare tier user & target
            userTierIdx = find(strcmp(app.TIER_LIST, app.Accounts.(app.MainApp.LoggedInUserId).Tier));
            targetTierIdx = find(strcmp(app.TIER_LIST, app.Accounts.(app.ListBox.Value).Tier));
            isHigherTier = userTierIdx <= targetTierIdx;

            % Edit Option
            app.ApplyChangesButton.Enable = true;
            app.ResetPasswordButton.Enable = true;

            if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option2 == 2 % Lower Tiers

                app.ApplyChangesButton.Enable = isHigherTier;
                app.ResetPasswordButton.Enable = isHigherTier;

            end

            % Delete Option
            app.DeleteButton.Enable = true;

            if app.Accounts.(app.MainApp.LoggedInUserId).Authority.Auth5.Option5 == 2 % Lower Tiers

                app.DeleteButton.Enable = isHigherTier;

            end

        end

    end
    
    methods (Access = public)
        
        function UpdateAccountForms(app)            

            ManageAccountFormAction('Check', '', '', app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
            tmp = load(app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
            app.AffiliationDropDown.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).Affiliation;
            app.EmailDomainDropDown.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).EmailDomain;
            app.AffiliationDropdown_2.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).Affiliation;
            app.EmailDomainDropDown_2.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).EmailDomain;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)

            app.MainApp = MainApp;            
            InitialLoadAccountList(app);      
            UpdateAccountForms(app);
            ListBoxValueChanged(app, []);
            ApplyAuthoritySetting(app);

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            app.IDEditField.Value = app.ListBox.Value;
            app.NameEditField.Value = app.Accounts.(app.ListBox.Value).Name;
            app.TierDropDown.Value = app.Accounts.(app.ListBox.Value).Tier;
            app.AffiliationDropDown.Value = app.AffiliationDropDown.Items{app.Accounts.(app.ListBox.Value).AffiliationIndex};
            app.EmailNameEditField.Value = app.Accounts.(app.ListBox.Value).EmailName;
            app.EmailDomainDropDown.Value = app.EmailDomainDropDown.Items{app.Accounts.(app.ListBox.Value).EmailDomainIndex};
            app.PhoneNumberEditField.Value = app.Accounts.(app.ListBox.Value).PhoneNumber;
            ApplyAuthorityTarget(app);

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            id = app.ListBox.Value;
            tmpAccounts = rmfield(app.Accounts, id);
            fieldNames = fieldnames(tmpAccounts);            
            numAdmin = 0;

            for i = 1:length(fieldNames)

                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);                

            end
            
            if ~numAdmin

                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Delete error');

                return;

            end

            app.Accounts = tmpAccounts;            
            RenewAccountList(app);

        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            
            tmpAccounts = app.Accounts;
            prevId = app.ListBox.Value;
            newId = app.IDEditField.Value;
            newName = app.NameEditField.Value;
            newEmailName = app.EmailNameEditField.Value;
            newPhoneNumber = app.PhoneNumberEditField.Value;
            [isValid, stateMsg] = ValidateInput('ID', newId, '');

            if ~isValid

                uialert(app.UIFigure, stateMsg, 'Invalid ID'); 

                return; 

            end

            [isValid, stateMsg] = ValidateInput('Name', newName, '');

            if ~isValid 

                uialert(app.UIFigure, stateMsg, 'Invalid Name'); 

                return; 

            end
            
            [isValid, stateMsg] = ValidateInput('EmailName', newEmailName, '');

            if ~isValid 

                uialert(app.UIFigure, stateMsg, 'Invalid Email Name'); 

                return; 

            end

            [isValid, stateMsg] = ValidateInput('PhoneNumber', newPhoneNumber, '');

            if ~isValid 

                uialert(app.UIFigure, stateMsg, 'Invalid Phone Number'); 

                return; 

            end

            newPhoneNumber(strfind(newPhoneNumber, '-')) = '';
            app.PhoneNumberEditField.Value = newPhoneNumber;
            tmpAccounts.(prevId).Tier = app.TierDropDown.Value;
            fieldNames = fieldnames(tmpAccounts);
            % Count Number of Admin (at least 1)
            numAdmin = 0;

            for i = 1:length(fieldNames)

                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);

            end

            if ~numAdmin

                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Tier changing error');

                return;

            end

            app.Accounts = renameStructField(app.Accounts, prevId, newId);
            app.Accounts.(newId).Tier = app.TierDropDown.Value;
            app.Accounts.(newId).Name = newName;
            app.Accounts.(newId).AffiliationIndex = find(strcmp(app.AffiliationDropDown.Items, app.AffiliationDropDown.Value));
            app.Accounts.(newId).EmailName = newEmailName;
            app.Accounts.(newId).EmailDomainIndex = find(strcmp(app.EmailDomainDropDown.Items, app.EmailDomainDropDown.Value));
            app.Accounts.(newId).PhoneNumber = newPhoneNumber;
            RenewAccountList(app);

        end

        % Button pushed function: ResetPasswordButton
        function ResetPasswordButtonPushed(app, event)
            
            PASSWORD_LENGTH = 16;
            id = app.ListBox.Value;
            msg = sprintf('Are you sure you want to reset password for account "%s"?', id);
            selection = uiconfirm(app.UIFigure, msg, 'Confirmation', ...
                'Options', {'OK', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, ...
                'Icon', 'warning');
            
            if strcmp(selection, 'Cancel')
            
                return; 

            end

            password = GenerateRandomPassword(PASSWORD_LENGTH);
            clipboard('copy', password);
            hashedPassword = StringToHashedHex(password);
            app.Accounts.(id).Password = hashedPassword;
            msg = sprintf('New Password\n\n    %s\n\nis copied to Clipboard', password);
            uiconfirm(app.UIFigure, msg, 'Confirm', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');

        end

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            
            id = app.IDEditField_2.Value;
            password = app.PasswordEditField_2.Value;
            name = app.NameEditField_2.Value;
            tier = app.TierDropDown_2.Value;
            affiliationIndex = find(strcmp(app.AffiliationDropDown.Items, app.AffiliationDropdown_2.Value));
            emailName = app.EmailNameEditField_2.Value;
            emailDomainIndex = find(strcmp(app.EmailDomainDropDown.Items, app.EmailDomainDropDown_2.Value));
            phoneNumber = app.PhoneNumberEditField_2.Value;

            [isGenerated, stateMsg, newAccountStruct] =...
                GenerateAccount(id, password, name, tier, affiliationIndex, ...
                emailName, emailDomainIndex, phoneNumber, app.Accounts);

            if ~isGenerated

                uialert(app.UIFigure, stateMsg, 'Create account error'); 

                return; 
                
            end
            
            app.Accounts = newAccountStruct;
            RenewAccountList(app);
            app.IDEditField_2.Value = '';
            app.PasswordEditField_2.Value = '';
            app.NameEditField_2.Value = '';
            app.TierDropDown_2.Value = app.TierDropDown_2.Items{1};
            app.AffiliationDropdown_2.Value = app.AffiliationDropdown_2.Items{1};
            app.EmailNameEditField_2.Value = '';
            app.EmailDomainDropDown_2.Value = app.EmailDomainDropDown_2.Items{1};
            app.PhoneNumberEditField_2.Value = '';
            msg = sprintf('The account "%s" has been successfully created.', id);
            uiconfirm(app.UIFigure, msg, 'Completed', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');

        end

        % Button pushed function: AuthoritySettingButton
        function AuthoritySettingButtonPushed(app, event)
                        
            authApp = ManageAuthority(app);
            waitfor(authApp.UIFigure);

            if isequal(app.Accounts, app.OrgAccounts)

                return;

            end

            ApplyAuthoritySetting(app);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            UIFigureCloseRequest(app, []);

        end

        % Button pushed function: AffiliationSettingButton, 
        % ...and 1 other component
        function AffiliationSettingButtonPushed(app, event)
            
            ManageAccountForm(app, app.ACCOUNT_FORM_TYPE_AFFILIATION, app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);

        end

        % Button pushed function: EmailSettingButton, EmailSettingButton_2
        function MailDomainSettingButtonPushed(app, event)
            
            ManageAccountForm(app, app.ACCOUNT_FORM_TYPE_MAILDOMAIN, app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if isequal(app.Accounts, app.OrgAccounts)

                delete(app);

                return;

            end

            selection = uiconfirm(app.UIFigure, 'Apply changes?', 'Confirmation', ...
                'Options', {'OK', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);

            if strcmp(selection, 'Cancel')

                return;

            end

            if strcmp(selection, 'No')

                delete(app);

                return;

            end

            Accounts = app.Accounts;
            save(app.ACCOUNT_FILENAME, "Accounts");
            delete(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Manage Account';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.DeleteButton = uibutton(app.GridLayout3, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformationPanel = uipanel(app.GridLayout4);
            app.InformationPanel.Title = 'Information';
            app.GridLayout5 = uigridlayout(app.InformationPanel);
            app.IDEditFieldLabel = uilabel(app.GridLayout5);
            app.IDEditFieldLabel.Text = 'ID :';
            app.IDEditField = uieditfield(app.GridLayout5, 'text');
            app.NameEditFieldLabel = uilabel(app.GridLayout5);
            app.NameEditFieldLabel.Text = 'Name :';
            app.NameEditField = uieditfield(app.GridLayout5, 'text');
            app.TierDropDownLabel = uilabel(app.GridLayout5);
            app.TierDropDownLabel.Text = 'Tier :';
            app.TierDropDown = uidropdown(app.GridLayout5);
            app.TierDropDown.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown.Value = 'Admin';
            app.AffiliationDropDownLabel = uilabel(app.GridLayout5);
            app.AffiliationDropDownLabel.Text = 'Affiliation :';
            app.AffiliationDropDown = uidropdown(app.GridLayout5);
            app.AffiliationDropDown.Items = {};
            app.AffiliationDropDown.Value = {};
            app.AffiliationSettingButton = uibutton(app.GridLayout5, 'push');
            app.AffiliationSettingButton.ButtonPushedFcn = createCallbackFcn(app, @AffiliationSettingButtonPushed, true);
            app.AffiliationSettingButton.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.AffiliationSettingButton.Text = '';
            app.EmailEditFieldLabel = uilabel(app.GridLayout5);
            app.EmailEditFieldLabel.Text = 'E-mail:';
            app.EmailNameEditField = uieditfield(app.GridLayout5, 'text');
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '@';
            app.EmailDomainDropDown = uidropdown(app.GridLayout5);
            app.EmailDomainDropDown.Items = {};
            app.EmailDomainDropDown.Value = {};
            app.EmailSettingButton = uibutton(app.GridLayout5, 'push');
            app.EmailSettingButton.ButtonPushedFcn = createCallbackFcn(app, @MailDomainSettingButtonPushed, true);
            app.EmailSettingButton.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.EmailSettingButton.Text = '';
            app.PhoneEditFieldLabel = uilabel(app.GridLayout5);
            app.PhoneEditFieldLabel.Text = 'Phone :';
            app.PhoneNumberEditField = uieditfield(app.GridLayout5, 'text');
            app.ApplyChangesButton = uibutton(app.GridLayout5, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.AuthoritySettingButton = uibutton(app.GridLayout5, 'push');
            app.AuthoritySettingButton.ButtonPushedFcn = createCallbackFcn(app, @AuthoritySettingButtonPushed, true);
            app.AuthoritySettingButton.Text = 'Authority Setting';
            app.ResetPasswordButton = uibutton(app.GridLayout4, 'push');
            app.ResetPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPasswordButtonPushed, true);
            app.ResetPasswordButton.Text = 'Reset Password';
            app.CreateAccountPanel = uipanel(app.GridLayout4);
            app.CreateAccountPanel.Title = 'Create Account';
            app.GridLayout6 = uigridlayout(app.CreateAccountPanel);
            app.IDEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.IDEditField_2.Placeholder = 'ID';
            app.PasswordEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.PasswordEditField_2.Placeholder = 'Password';
            app.NameEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.NameEditField_2.Placeholder = 'Name';
            app.TierDropDown_2 = uidropdown(app.GridLayout6);
            app.TierDropDown_2.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown_2.Placeholder = 'Select a Tier';
            app.TierDropDown_2.Value = 'Staff';
            app.AffiliationDropdown_2 = uidropdown(app.GridLayout6);
            app.AffiliationDropdown_2.Items = {};
            app.AffiliationDropdown_2.Value = {};
            app.AffiliationSettingButton_2 = uibutton(app.GridLayout6, 'push');
            app.AffiliationSettingButton_2.ButtonPushedFcn = createCallbackFcn(app, @AffiliationSettingButtonPushed, true);
            app.AffiliationSettingButton_2.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.AffiliationSettingButton_2.Text = '';
            app.EmailNameEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.EmailNameEditField_2.Placeholder = 'E-mail';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '@';
            app.EmailDomainDropDown_2 = uidropdown(app.GridLayout6);
            app.EmailDomainDropDown_2.Items = {};
            app.EmailDomainDropDown_2.Value = {};
            app.EmailSettingButton_2 = uibutton(app.GridLayout6, 'push');
            app.EmailSettingButton_2.ButtonPushedFcn = createCallbackFcn(app, @MailDomainSettingButtonPushed, true);
            app.EmailSettingButton_2.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.EmailSettingButton_2.Text = '';
            app.PhoneNumberEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.PhoneNumberEditField_2.Placeholder = 'Phone number';
            app.CreateAccountButton = uibutton(app.GridLayout6, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.DoneButton = uibutton(app.GridLayout7, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccount(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end