classdef ManageAuthority < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        EditAuthorityDropDown       matlab.ui.control.DropDown
        EditAuthorityLabel          matlab.ui.control.Label
        EditTierDropDown            matlab.ui.control.DropDown
        EditTierLabel               matlab.ui.control.Label
        DeleteDropDown              matlab.ui.control.DropDown
        DeleteDropDownLabel         matlab.ui.control.Label
        EditDropDown_2              matlab.ui.control.DropDown
        EditDropDown_2Label         matlab.ui.control.Label
        CreateDropDown              matlab.ui.control.DropDown
        CreateDropDownLabel         matlab.ui.control.Label
        ManageAccountsCheckBox      matlab.ui.control.CheckBox
        ResetButton                 matlab.ui.control.Button
        EditDropDown                matlab.ui.control.DropDown
        EditDropDownLabel           matlab.ui.control.Label
        AccessDropDown              matlab.ui.control.DropDown
        AccessDropDownLabel         matlab.ui.control.Label
        CurrentUserLabel            matlab.ui.control.Label
        AnalysisCheckBox            matlab.ui.control.CheckBox
        SaveSPRImagesCheckBox       matlab.ui.control.CheckBox
        AutosamplerSettingCheckBox  matlab.ui.control.CheckBox
        ManageAudittrailCheckBox    matlab.ui.control.CheckBox
        DoneButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)

        ParentApp;                

    end
    
    properties (Access = private)

        currentAccount;
        currentId;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ParentApp)
            
            app.ParentApp = ParentApp;
            app.currentId = ParentApp.ListBox.Value;
            app.currentAccount = app.ParentApp.Accounts.(app.currentId);
            app.CurrentUserLabel.Text = ...
                sprintf('%s (%s, %s)', app.currentId, ...
                app.ParentApp.Accounts.(app.currentId).Name, ...
                app.ParentApp.Accounts.(app.currentId).Tier);

            ResetButtonPushed(app, []);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Authority 1
            app.AnalysisCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Accessible;            
            % Options
            app.AccessDropDown.Value = app.AccessDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Option1};
            app.EditDropDown.Value = app.EditDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Option2};

            % Authority 2
            app.SaveSPRImagesCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth2.Accessible;

            % Authority 3
            app.AutosamplerSettingCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth3.Accessible;

            % Authority 4
            app.ManageAudittrailCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth4.Accessible;     

            % Authority 5
            app.ManageAccountsCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Accessible;            
            % Options
            app.CreateDropDown.Value = app.CreateDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Option1};
            app.EditDropDown_2.Value = app.EditDropDown_2.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Option2};
            app.EditTierDropDown.Value = app.EditTierDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Option3};
            app.EditAuthorityDropDown.Value = app.EditAuthorityDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Option4};
            app.DeleteDropDown.Value = app.DeleteDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth5.Option5};

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
           UIFigureCloseRequest(app, event);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             
            % Authority 1
            app.currentAccount.Authority.Auth1.Accessible = app.AnalysisCheckBox.Value;
            % Options
            app.currentAccount.Authority.Auth1.Option1 = find(strcmp(app.AccessDropDown.Items, app.AccessDropDown.Value));
            app.currentAccount.Authority.Auth1.Option2 = find(strcmp(app.EditDropDown.Items, app.EditDropDown.Value));

            % Authority 2
            app.currentAccount.Authority.Auth2.Accessible = app.SaveSPRImagesCheckBox.Value;

            % Authority 3
            app.currentAccount.Authority.Auth3.Accessible = app.AutosamplerSettingCheckBox.Value;

            % Authority 4
            app.currentAccount.Authority.Auth4.Accessible = app.ManageAudittrailCheckBox.Value;
            
            % Authority 5
            app.currentAccount.Authority.Auth5.Accessible = app.ManageAccountsCheckBox.Value;
            % Options
            app.currentAccount.Authority.Auth5.Option1 = find(strcmp(app.CreateDropDown.Items, app.CreateDropDown.Value));
            app.currentAccount.Authority.Auth5.Option2 = find(strcmp(app.EditDropDown_2.Items, app.EditDropDown_2.Value));
            app.currentAccount.Authority.Auth5.Option3 = find(strcmp(app.EditTierDropDown.Items, app.EditTierDropDown.Value));
            app.currentAccount.Authority.Auth5.Option4 = find(strcmp(app.EditAuthorityDropDown.Items, app.EditAuthorityDropDown.Value));
            app.currentAccount.Authority.Auth5.Option5 = find(strcmp(app.DeleteDropDown.Items, app.DeleteDropDown.Value));
            
            
            if isequal(app.ParentApp.Accounts.(app.currentId), app.currentAccount)

               delete(app);

               return;

            end
            
            select = uiconfirm(app.UIFigure,...
                'Save Changes?', 'Confirmation',...
                'Options', {'OK', 'No', 'Cancel'},...
                'DefaultOption', 1, 'CancelOption', 3);

            if strcmp(select, 'Cancel')

                return;

            end

            if strcmp(select, 'OK')

                app.ParentApp.Accounts.(app.currentId) = app.currentAccount;

            end            
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Manage authority';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ManageAudittrailCheckBox = uicheckbox(app.GridLayout);
            app.ManageAudittrailCheckBox.Text = 'Manage Audit-trail';
            app.AutosamplerSettingCheckBox = uicheckbox(app.GridLayout);
            app.AutosamplerSettingCheckBox.Text = 'Autosampler Setting';
            app.SaveSPRImagesCheckBox = uicheckbox(app.GridLayout);
            app.SaveSPRImagesCheckBox.Text = 'Save SPR Images';
            app.AnalysisCheckBox = uicheckbox(app.GridLayout);
            app.AnalysisCheckBox.Text = 'Analysis';
            app.CurrentUserLabel = uilabel(app.GridLayout);
            app.CurrentUserLabel.Text = 'cdseo (Manager)';
            app.AccessDropDownLabel = uilabel(app.GridLayout);
            app.AccessDropDownLabel.Text = 'Access :';
            app.AccessDropDown = uidropdown(app.GridLayout);
            app.AccessDropDown.Items = {'Deny', 'Own Data', 'Team Data', 'All Data'};
            app.AccessDropDown.Value = 'Deny';
            app.EditDropDownLabel = uilabel(app.GridLayout);
            app.EditDropDownLabel.Text = 'Edit :';
            app.EditDropDown = uidropdown(app.GridLayout);
            app.EditDropDown.Items = {'Deny', 'Own Data', 'Team Data', 'All Data'};
            app.EditDropDown.Value = 'Deny';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ManageAccountsCheckBox = uicheckbox(app.GridLayout);
            app.ManageAccountsCheckBox.Text = 'Manage Accounts';
            app.CreateDropDownLabel = uilabel(app.GridLayout);
            app.CreateDropDownLabel.Text = 'Create :';
            app.CreateDropDown = uidropdown(app.GridLayout);
            app.CreateDropDown.Items = {'Deny', 'Lower tiers', 'All tiers'};
            app.CreateDropDown.Value = 'Deny';
            app.EditDropDown_2Label = uilabel(app.GridLayout);
            app.EditDropDown_2Label.Text = 'Edit :';
            app.EditDropDown_2 = uidropdown(app.GridLayout);
            app.EditDropDown_2.Items = {'Deny', 'Lower tiers', 'All tiers'};
            app.EditDropDown_2.Value = 'Deny';
            app.DeleteDropDownLabel = uilabel(app.GridLayout);
            app.DeleteDropDownLabel.Text = 'Delete :';
            app.DeleteDropDown = uidropdown(app.GridLayout);
            app.DeleteDropDown.Items = {'Deny', 'Lower tiers', 'All tiers'};
            app.DeleteDropDown.Value = 'Deny';
            app.EditTierLabel = uilabel(app.GridLayout);
            app.EditTierLabel.Text = 'Edit - Tier :';
            app.EditTierDropDown = uidropdown(app.GridLayout);
            app.EditTierDropDown.Items = {'Deny', 'Allow'};
            app.EditTierDropDown.Value = 'Deny';
            app.EditAuthorityLabel = uilabel(app.GridLayout);
            app.EditAuthorityLabel.Text = 'Edit - Authority :';
            app.EditAuthorityDropDown = uidropdown(app.GridLayout);
            app.EditAuthorityDropDown.Items = {'Deny', 'Allow'};
            app.EditAuthorityDropDown.Value = 'Deny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAuthority(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end