classdef WorkingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Tier4StaffPanel       matlab.ui.container.Panel
        CheckBox_4            matlab.ui.control.CheckBox
        ListBox_4             matlab.ui.control.ListBox
        ListBox_4Label        matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        LogOutButton          matlab.ui.control.Button
        Tier3SeniorPanel      matlab.ui.container.Panel
        CheckBox_3            matlab.ui.control.CheckBox
        ListBox_3             matlab.ui.control.ListBox
        ListBox_3Label        matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        ChangePasswordButton  matlab.ui.control.Button
        Tier2ManagerPanel     matlab.ui.container.Panel
        CheckBox_2            matlab.ui.control.CheckBox
        ListBox_2             matlab.ui.control.ListBox
        ListBox_2Label        matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        Tier1AdminPanel       matlab.ui.container.Panel
        CheckBox              matlab.ui.control.CheckBox
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        ManageAccountsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        ListTiers = {'Admin', 'Manager', 'Senior', 'Staff'};
        CurrentTier = '';
        IsLoggedIn = false;
        CurrentId;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '';
            % Setting object tier
            targetObjects = findobj(app.UIFigure, '-property', 'UserData');            
            set(targetObjects, 'Visible', 'Off');
            set(app.UIFigure, 'Visible', 'On');
            for i = 1:size(targetObjects, 1)
                targetObjects(i).UserData.AccessibleTier = app.ListTiers{4};
            end

            accessibleObjects.(app.ListTiers{1}) = {
                'ManageAccountsButton';
                'Label';
                'CheckBox';
                'ListBox';
                'Tier1AdminPanel';
                };
            accessibleObjects.(app.ListTiers{2}) = {
                'Button_2';
                'Label_2';
                'CheckBox_2';
                'ListBox_2';
                'Tier2ManagerPanel';
                };
            accessibleObjects.(app.ListTiers{3}) = {
                'ChangePasswordButton';
                'Label_3';
                'CheckBox_3';
                'ListBox_3';
                'Tier3SeniorPanel';
                };
            accessibleObjects.(app.ListTiers{4}) = {
                'LogOutButton';
                'Label_4';
                'CheckBox_4';
                'ListBox_4';
                'Tier4StaffPanel';
                };            
            loginApp = LoginWindow(app);
            waitfor(loginApp.UIFigure);   
            if ~app.IsLoggedIn; return; end

            userInfo = sprintf('%s(%s)', app.CurrentId, app.CurrentTier);
            app.UIFigure.Name = userInfo;
            tierIdx = find(strcmpi(app.CurrentTier, app.ListTiers));
            for i = tierIdx:size(app.ListTiers, 2)                
                tmp = accessibleObjects.(app.ListTiers{i});
                for ii = 1:size(tmp, 1)
                    set(app.(tmp{ii}), 'Visible', 'On');
                end
            end
        end

        % Button pushed function: ManageAccountsButton
        function ManageAccountsButtonPushed(app, event)
            ManageAccount;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: ChangePasswordButton
        function ChangePasswordButtonPushed(app, event)
            ChangePassword(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Working Window';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tier1AdminPanel = uipanel(app.UIFigure);
            app.Tier1AdminPanel.Title = 'Tier1 (Admin)';
            app.ManageAccountsButton = uibutton(app.Tier1AdminPanel, 'push');
            app.ManageAccountsButton.ButtonPushedFcn = createCallbackFcn(app, @ManageAccountsButtonPushed, true);
            app.ManageAccountsButton.Tag = 'Admin';
            app.ManageAccountsButton.Text = 'Manage Accounts';
            app.Label = uilabel(app.Tier1AdminPanel);
            app.Label.Tag = 'Admin';
            app.ListBoxLabel = uilabel(app.Tier1AdminPanel);
            app.ListBoxLabel.Tag = 'Admin';
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Tier1AdminPanel);
            app.ListBox.Tag = 'Admin';
            app.CheckBox = uicheckbox(app.Tier1AdminPanel);
            app.CheckBox.Tag = 'Admin';
            app.Tier2ManagerPanel = uipanel(app.UIFigure);
            app.Tier2ManagerPanel.Title = 'Tier2 (Manager)';
            app.Button_2 = uibutton(app.Tier2ManagerPanel, 'push');
            app.Button_2.Tag = 'Manager';
            app.Label_2 = uilabel(app.Tier2ManagerPanel);
            app.Label_2.Tag = 'Manager';
            app.ListBox_2Label = uilabel(app.Tier2ManagerPanel);
            app.ListBox_2Label.Tag = 'Manager';
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.Tier2ManagerPanel);
            app.ListBox_2.Tag = 'Manager';
            app.CheckBox_2 = uicheckbox(app.Tier2ManagerPanel);
            app.CheckBox_2.Tag = 'Manager';
            app.Tier3SeniorPanel = uipanel(app.UIFigure);
            app.Tier3SeniorPanel.Title = 'Tier3 (Senior)';
            app.ChangePasswordButton = uibutton(app.Tier3SeniorPanel, 'push');
            app.ChangePasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePasswordButtonPushed, true);
            app.ChangePasswordButton.Tag = 'Senior';
            app.ChangePasswordButton.Text = 'Change Password';
            app.Label_3 = uilabel(app.Tier3SeniorPanel);
            app.Label_3.Tag = 'Senior';
            app.ListBox_3Label = uilabel(app.Tier3SeniorPanel);
            app.ListBox_3Label.Tag = 'Senior';
            app.ListBox_3Label.Text = 'List Box';
            app.ListBox_3 = uilistbox(app.Tier3SeniorPanel);
            app.ListBox_3.Tag = 'Senior';
            app.CheckBox_3 = uicheckbox(app.Tier3SeniorPanel);
            app.CheckBox_3.Tag = 'Senior';
            app.Tier4StaffPanel = uipanel(app.UIFigure);
            app.Tier4StaffPanel.Title = 'Tier4 (Staff)';
            app.LogOutButton = uibutton(app.Tier4StaffPanel, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Tag = 'Staff';
            app.LogOutButton.Text = 'Log-Out';
            app.Label_4 = uilabel(app.Tier4StaffPanel);
            app.Label_4.Tag = 'Staff';
            app.ListBox_4Label = uilabel(app.Tier4StaffPanel);
            app.ListBox_4Label.Tag = 'Staff';
            app.ListBox_4Label.Text = 'List Box';
            app.ListBox_4 = uilistbox(app.Tier4StaffPanel);
            app.ListBox_4.Tag = 'Staff';
            app.CheckBox_4 = uicheckbox(app.Tier4StaffPanel);
            app.CheckBox_4.Tag = 'Staff';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end