classdef test_Authority < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        ChangePasswordMenu  matlab.ui.container.Menu
        Menu2_2             matlab.ui.container.Menu
        Menu3               matlab.ui.container.Menu
        Menu3_2             matlab.ui.container.Menu
        AudittrailMenu      matlab.ui.container.Menu
        Menu2_3             matlab.ui.container.Menu
        ManageaccountsMenu  matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
        SaveMenu            matlab.ui.container.Menu
        AnalysisMenu        matlab.ui.container.Menu
        UserLabel           matlab.ui.control.Label
        LoginButton         matlab.ui.control.Button
        AutoSamplerPanel    matlab.ui.container.Panel
        SettingPanel        matlab.ui.container.Panel
        EditField           matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        Button2             matlab.ui.control.Button
        CheckBox2           matlab.ui.control.CheckBox
        RunningPanel        matlab.ui.container.Panel
        CheckBox            matlab.ui.control.CheckBox
        Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        LoggedInUserId = ''; % '' : Logged out        
        Accounts;

    end
    
    properties (Access = private)


    end
    
    methods (Access = private)

        function RenewAccountInformation(app)

            app.UserLabel.Text = sprintf('%s', app.LoggedInUserId);            

            if isempty(app.LoggedInUserId)

                targetObjects = findobj(app.UIFigure, '-property', 'Visible');            
                set(targetObjects, 'Visible', 'Off');
                set(app.UIFigure, 'Visible', 'On');
                set(app.LoginButton, 'Visible', 'On');

                return;

            end

            targetObjects = findobj(app.UIFigure, '-property', 'Visible');            
            set(targetObjects, 'Visible', 'on');

            app.AnalysisMenu.Visible = app.Accounts.(app.LoggedInUserId).Authority.Auth1.Accessible;
            app.SaveMenu.Visible = app.Accounts.(app.LoggedInUserId).Authority.Auth2.Accessible;
            app.SettingPanel.Visible = app.Accounts.(app.LoggedInUserId).Authority.Auth3.Accessible;
            app.AudittrailMenu.Visible = app.Accounts.(app.LoggedInUserId).Authority.Auth4.Accessible;
            app.ManageaccountsMenu.Visible = app.Accounts.(app.LoggedInUserId).Authority.Auth5.Accessible;

        end

    end
    
    methods (Access = public)
        
        function ReloadAccounts(app)

            loadedStruct = load('accounts.mat', 'Accounts');
            app.Accounts = loadedStruct.Accounts;                   

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            ReloadAccounts(app);
            RenewAccountInformation(app);

        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            
            if strcmp(app.LoginButton.Text, 'Log-in')

                loginApp = LoginWindow(app);
                waitfor(loginApp.UIFigure);

                if isempty(app.LoggedInUserId)

                    return;

                end
                
                app.LoginButton.Text = 'Log-out';

            else

                app.LoggedInUserId = '';
                app.LoginButton.Text = 'Log-in';

            end

            RenewAccountInformation(app);

        end

        % Menu selected function: ManageaccountsMenu
        function ManageaccountsMenuSelected(app, event)
            
            manageApp = ManageAccount(app);
            waitfor(manageApp.UIFigure);

            ReloadAccounts(app);
            RenewAccountInformation(app);

        end

        % Menu selected function: ChangePasswordMenu
        function ChangePasswordMenuSelected(app, event)
            
            ChangePassword(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ChangePasswordMenu = uimenu(app.Menu);
            app.ChangePasswordMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePasswordMenuSelected, true);
            app.ChangePasswordMenu.Text = 'Change Password';
            app.Menu2_2 = uimenu(app.Menu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.Menu3_2 = uimenu(app.UIFigure);
            app.Menu3_2.Text = 'Menu3';
            app.AudittrailMenu = uimenu(app.Menu3_2);
            app.AudittrailMenu.Text = 'Audit-trail';
            app.Menu2_3 = uimenu(app.Menu3_2);
            app.Menu2_3.Text = 'Menu2';
            app.ManageaccountsMenu = uimenu(app.Menu3_2);
            app.ManageaccountsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManageaccountsMenuSelected, true);
            app.ManageaccountsMenu.Text = 'Manage accounts';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.SaveMenu = uimenu(app.Menu2);
            app.SaveMenu.Text = 'Save';
            app.AnalysisMenu = uimenu(app.Menu2);
            app.AnalysisMenu.Text = 'Analysis';
            app.AutoSamplerPanel = uipanel(app.UIFigure);
            app.AutoSamplerPanel.Title = 'AutoSampler';
            app.RunningPanel = uipanel(app.AutoSamplerPanel);
            app.RunningPanel.Title = 'Running';
            app.Button = uibutton(app.RunningPanel, 'push');
            app.CheckBox = uicheckbox(app.RunningPanel);
            app.SettingPanel = uipanel(app.AutoSamplerPanel);
            app.SettingPanel.Title = 'Setting';
            app.CheckBox2 = uicheckbox(app.SettingPanel);
            app.CheckBox2.Text = 'Check Box2';
            app.Button2 = uibutton(app.SettingPanel, 'push');
            app.Button2.Text = 'Button2';
            app.EditFieldLabel = uilabel(app.SettingPanel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.SettingPanel, 'numeric');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Log-in';
            app.UserLabel = uilabel(app.UIFigure);
            app.UserLabel.Text = 'id (name)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_Authority

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end